classdef MrScopev2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RecordSettingsPanel             matlab.ui.container.Panel
        RecordButton                    matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        SnapshotButton                  matlab.ui.control.Button
        RecordinglengthsEditFieldLabel  matlab.ui.control.Label
        RecordinglengthsEditField       matlab.ui.control.NumericEditField
        ConnecttoMrScopeButton          matlab.ui.control.Button
        DisconnecttoMrScopeButton       matlab.ui.control.Button
        MrScopeSettingsPanel            matlab.ui.container.Panel
        ExcitationLEDLabel              matlab.ui.control.Label
        ExcitationLEDSlider             matlab.ui.control.Slider
        Spinner                         matlab.ui.control.Spinner
        GainSliderLabel                 matlab.ui.control.Label
        GainSlider                      matlab.ui.control.Slider
        FramerateLabel                  matlab.ui.control.Label
        FramerateSlider                 matlab.ui.control.Slider
        ColormapDropDown_2              matlab.ui.control.DropDown
        ColormapDropDown_3              matlab.ui.control.DropDown
        StudyRecordPanel                matlab.ui.container.Panel
        AnimalnameEditFieldLabel        matlab.ui.control.Label
        AnimalnameEditField             matlab.ui.control.EditField
        ExperimentnameEditFieldLabel    matlab.ui.control.Label
        ExperimentnameEditField         matlab.ui.control.EditField
        OKButton                        matlab.ui.control.Button
        ClearIndexButton                matlab.ui.control.Button
        MessageTextAreaLabel            matlab.ui.control.Label
        MessageTextArea                 matlab.ui.control.TextArea
        RealtimeimagePanel_2            matlab.ui.container.Panel
        ColormapDropDown_4Label         matlab.ui.control.Label
        ColormapDropDown_4              matlab.ui.control.DropDown
        DynamicRangePanel_2             matlab.ui.container.Panel
        OKButton_3                      matlab.ui.control.Button
        Spinner_6                       matlab.ui.control.Spinner
        Spinner_7                       matlab.ui.control.Spinner
        FPSEditFieldLabel               matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        MotorControlPanel               matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        RecordinglengthsEditFieldLabel_2  matlab.ui.control.Label
        RightEditField                  matlab.ui.control.EditField
        LeftEditField                   matlab.ui.control.EditField
        StartupButton                   matlab.ui.control.Button
        LeftSpinner                     matlab.ui.control.Spinner
        LeftSpinnerLabel                matlab.ui.control.Label
        RightSpinner                    matlab.ui.control.Spinner
        RightSpinnerLabel               matlab.ui.control.Label
        TogetherSpinner                 matlab.ui.control.Spinner
        TogetherSpinnerLabel            matlab.ui.control.Label
        RecordinglengthsEditFieldLabel_3  matlab.ui.control.Label
        RecordinglengthsEditFieldLabel_4  matlab.ui.control.Label
        LeftEditField_2                 matlab.ui.control.EditField
        LeftEditField_3                 matlab.ui.control.EditField
        RightEditField_2                matlab.ui.control.EditField
        RightEditField_3                matlab.ui.control.EditField
        RecordinglengthsEditFieldLabel_5  matlab.ui.control.Label
        LeftEditField_4                 matlab.ui.control.EditField
        RightEditField_4                matlab.ui.control.EditField
        RecordinglengthsEditFieldLabel_6  matlab.ui.control.Label
        TimeGapEditField                matlab.ui.control.EditField
        GoButton                        matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        LockleftmoterButton             matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        LED % Description
        GAIN
        framerate
        cellArrayText % Description
        %str % Description
        path % Description
        MrScope_Connected % Description
        MrScope_record % Description
        MrScope_recordpause
        MrScope_recordstop
        read_button % Description
        colormap_value
        dynamic_value
        mean_value
        video
        stat % Description
        lower_value
        upper_value
        shot
        shotNum
        set_record_time
        gray
        set_time_record
        count
        %***传送给子窗口
        image
        note
        %位移台
        motor_Connected
        step_moving
        h1_loc
        h2_loc
        h1
        h2
%         h1_start
%         h2_start
        h1_stop
        h2_stop
        h1_step
        h2_step
        time_gap
        frame_count
%         threshold_en
    end
    
    properties (Access = private)
        MrScopev2_child % Description
        DialogApp
    end
    
    methods (Access = private)
        
       function output(app,str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText=horzcat(app.cellArrayText,string); % 水平串联数组
            app.MessageTextArea.Value = app.cellArrayText; % 给TextArea赋值
            fprintf(app.note,'%s\n',string);%take note
        end


        function Message(app,a,b,c,d)
            str = '';
            str = strcat(str,datestr(clock));
            if nargin==2
                str = strcat(str,string(a));
            elseif nargin==3
                str = strcat(str,string(a),string(b));
            elseif nargin==4
                str = strcat(str,string(a),string(b),string(c));
            elseif nargin==5
                str = strcat(str,string(a),string(b),string(c),string(d));    
            end
            output(app,str);
        end
        
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MrScopev2, i)
            % Store main app object
            addpath('.\mexopencv-master');
            addpath('.\apt project');
            addpath('.\code');
            app.stat = false;
            %*****message box 默认输出
            app.cellArrayText{1} = sprintf('%s\n', '***请连接MrScope***'); % 赋初值
            app.MessageTextArea.Value=app.cellArrayText{1}; % 文本区域中的初始显示信息
            %****存储路径
            app.path = sprintf('%s\n', '.\data');
            %cd 'E:\MRSCOPE_GUI';
            
            %****初始化参数
            %timer_init(app);
            app.MrScope_Connected = false;
            app.read_button = true;
            app.MrScope_record = false;
            app.MrScope_recordstop = false;
            app.MrScope_recordpause = false;
            app.colormap_value  = 'gray';
            app.mean_value = [];
            app.lower_value = 0;
            app.upper_value = 255;
            app.shot = false;
            app.shotNum = 0;
            app.set_time_record = false;
            app.LED = 0;
            app.GAIN = 1;
            app.framerate = 10;
            color_map = app.colormap_value;
            app.count=-1;
            save("./settings/color_map.mat","color_map");
            low = app.lower_value;
            save("./settings/low.mat","low");
            up = app.upper_value;
            save("./settings/up.mat","up");
            %****初始化时除连接按钮其它按钮失效
            set(app.DisconnecttoMrScopeButton,'Enable','off');
            set(app.RecordButton,'Enable','off');
            set(app.StopButton,'Enable','off');
            set(app.StopButton,'Enable','off');
            %             set(app.ChoosereferenceimageButton,'Enable','off');
            set(app.SnapshotButton,'Enable','off');
            %set(app.DynamicrangeButton,'Enable','off');
            %
            app.gray = imread('start_image.tif');
            imwrite(app.gray,'settings\1.tif');
            app.motor_Connected = false;
            app.step_moving = false;


        end

        % Callback function
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.LED = round(changingValue);
            app.ExcitationLEDSlider.Value=app.LED;
            
            % message box 输出
            Message(app," LED changed to ",changingValue);
        end

        % Callback function
        function ExcitationLEDSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner.Value=round(changingValue) ;
            app.LED = changingValue;
            % message box 输出
            Message(app," LED changed to ",round(changingValue));
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.path=strcat(app.path,'/',datestr(date));
            mkdir(app.path);
            app.path=strcat(app.path,'/',app.AnimalnameEditField.Value);
            mkdir(app.path);
            app.path=strcat(app.path,'/',app.ExperimentnameEditField.Value);
            mkdir(app.path);
            %cd(app.path);
            % message box 输出
            app.note = fopen(strcat(app.path,'/','note.txt'),'a');
            Message(app,string(app.path)," Folder created");
        end

        % Callback function
        function AnimalnameEditFieldValueChanged(app, event)
            value = app.AnimalnameEditField.Value;
            % message box 输出
            Message(app,' Animal name is ',value);
        end

        % Callback function
        function ExperimentnameEditFieldValueChanged(app, event)
            value = app.ExperimentnameEditField.Value;
            % message box 输出
            Message(app,' Experiment name is ',value);
        end

        % Button pushed function: ConnecttoMrScopeButton
        function ConnecttoMrScopeButtonPushed(app, event)
            %Message(app,' button work');
             if ConnectToCam()*SendInitCommands()*testCommand1(0,ledcommand(app.LED))*testCommand1(0,gaincommand(app.GAIN*16))*testCommand1(frameratecommand1(app.framerate),frameratecommand2(app.framerate))~=0
                 app.MrScope_Connected = true;
                 app.MrScope_record = false;
                 app.MrScope_recordstop = false;
                 set(app.ConnecttoMrScopeButton,'Enable','off');
                 set(app.DisconnecttoMrScopeButton,'Enable','on');
                 set(app.RecordButton,'Enable','on');
                 app.RecordButton.Text = 'Record';
                 %set(app.DynamicrangeButton,'Enable','on');
                 set(app.SnapshotButton,'Enable','on');
                 Message(app,' MrScope initialized successfully');
             else
                 Message(app,' camera failed to initialized');
                 return
             end
            cam = cv.VideoCapture(0);
            width = 752;
            height = 480;
            cam.set(3,width);
            cam.set(4,height);
            left_loc = 0;
            right_loc = 0;
            app.video = cv.VideoWriter();
            %****imshow capture
            %app.count = -1;
            tStart1=tic;
            frame_time=0;
            tlast=0;
            while (app.MrScope_Connected)
                %try
                tstart=tic;
                img=cam.read();
                app.image=img;
                
                if app.MrScope_record && (~app.MrScope_recordstop) && (~app.set_time_record)
                    if app.count == -1
                        tend1 = toc(tStart1);
                        tlast = tend1;
                        frame_time = 0;
                    end
                    app.count = app.count+1;
                    
                    tend1 = toc(tStart1)-tlast;
                    tlast = toc(tStart1);
                    frame_time = frame_time+tend1;
                    %dlmwrite(strcat(app.path,'\timeStamps.csv'),[count,round(frame_time*1000),0],'-append');
%                     if (~app.MrScope_recordpause)
%                         app.video.write(img);
%                     end
                    if (~app.MrScope_recordpause)
                        app.video.write(img);
                    else
                        img1 = app.gray;
                        app.video.write(img1);
                    end
                    
                end
                if app.MrScope_record && (~app.MrScope_recordstop) && app.set_time_record
                    if app.count == -1
                        tend1 = toc(tStart1);
                        tlast=tend1;
                    end
                    app.count = app.count + 1
%                     if app.framerate==10||app.framerate==15||app.framerate==20
%                         left_loc = app.h1.GetPosition_Position(0);
%                         right_loc = app.h2.GetPosition_Position(0);
%                         app.LeftEditField = left_loc;
%                         app.RightEditField = right_loc;
%                     end
                    tend2 = toc(tStart1);
                    tend3 = tend2-tlast;
                    tlast = toc(tStart1);
                    frame_time = frame_time+tend3;
                    app.video.write(img);
                    if tend2-tend1 >= app.set_record_time
                        StopButtonPushed(app);
                        app.set_record_time = 0;
                        app.set_time_record = false;
                    end
                    app.video.write(img);
                end
                tend=toc(tstart);
                if tend>1
                    img2=cam.retrieve();
                    if size(img2,1)==0
                        Message(app,' MrScope retrieve frame failed. Attempting to reconnect.');
                        pause(0.1);
                        if app.MrScope_record && (~app.MrScope_recordstop)
                            dlmwrite(strcat(app.path,'\timeStamps.csv'),[app.count,round(frame_time*1000),0,1111],'-append');
                        end
                    end
                else
                    dlmwrite(strcat(app.path,'\timeStamps.csv'),[app.count,round(frame_time*1000),0,0,left_loc,right_loc],'-append');
                    pause(1/app.framerate-tend);
                    tend=toc(tstart);
                    app.FPSEditField.Value=1/tend;
                end
                
                if app.framerate<=20 && app.motor_Connected
                    if app.step_moving
                        app.frame_count = app.frame_count + 1;
                        % 位移台控制
                        if app.frame_count == app.time_gap
                            if abs(double(app.h1_loc) - double(app.h1_stop)) + 0.0000001 >= abs(double(app.h1_step))
                                if double(app.h1_loc) - double(app.h1_stop) < 0
                                    app.h1_loc = app.h1_loc + app.h1_step;
                                else
                                    app.h1_loc = app.h1_loc - app.h1_step;
                                end
                                mat_moveabspos(app.h1,app.h1_loc);
                                Message(app,"The location of left moter is set to ", app.h1_loc);

                            end
                            if abs(double(app.h2_loc) - double(app.h2_stop)) + 0.0000001 >= abs(double(app.h2_step))
                                if double(app.h2_loc) - double(app.h2_stop) < 0
                                    app.h2_loc = app.h2_loc + app.h2_step;
                                else
                                    app.h2_loc = app.h2_loc - app.h2_step;
                                end
                                mat_moveabspos(app.h2,app.h2_loc);
                                Message(app,"The location of right moter is set to ", app.h2_loc);

                            end
                            app.frame_count = 0;

                        end
                        if abs(double(app.h1_loc) - double(app.h1_stop)) + 0.0000001 < abs(double(app.h1_step)) && abs(double(app.h2_loc) - double(app.h2_stop)) + 0.0000001  < abs(double(app.h2_step))  && app.frame_count == app.time_gap -1
                            app.step_moving = false;
                            Message(app,"Automatic moving stops.");
                        end


                    end
                    
                    left_loc = app.h1.GetPosition_Position(0);
                    right_loc = app.h2.GetPosition_Position(0);
                    app.LeftEditField.Value = num2str(left_loc);
                    app.RightEditField.Value = num2str(right_loc);
                end
                if size(img,1)==0
                    Message(app,' MrScope grab frame failed. Attempting to reconnect.');
                    if cam.isOpened()
                        cam.release();
                    end
                    if app.MrScope_record && (~app.MrScope_recordstop)
                        dlmwrite(strcat(app.path,'\timeStamps.csv'),[0,0,0,2222],'-append');
                    end
                    if ConnectToCam()*SendInitCommands()*testCommand1(0,ledcommand(app.LED))*testCommand1(0,gaincommand(app.GAIN*16))*testCommand1(frameratecommand1(app.framerate),frameratecommand2(app.framerate))~=0
                        cam = cv.VideoCapture(0);
                        Message(app,' MrScope reconnected successfully');
                    end
                    pause(0.1);
                    continue;
                end
                if app.shot
                    filename = strcat(app.path, '/',num2str(app.shotNum),'.tif');
                    imwrite(img,filename);
                    Message(app,"Snapshot ", num2str(app.shotNum),".tif saved");
                    app.shot=false;
                end
                imwrite(img,'.\settings\1.tif');
                if app.MrScope_recordstop
                    app.video.release();
                end


             end
            if (~app.MrScope_Connected)
                cam.release();
                Message(app,' camera disconnected');
            end
            

        end

        % Button pushed function: DisconnecttoMrScopeButton
        function DisconnecttoMrScopeButtonPushed(app, event)
            app.MrScope_Connected = false;
            Message(app,' camera disconnected');
            set(app.ConnecttoMrScopeButton,'Enable','on');
            set(app.DisconnecttoMrScopeButton,'Enable','off');
            set(app.RecordButton,'Enable','off');
            %set(app.DynamicrangeButton,'Enable','off');
            set(app.SnapshotButton,'Enable','off');
            %set(app.ChoosereferenceimageButton,'Enable','off');
            set(app.StopButton,'Enable','off');
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            
            st =  app.RecordButton.Text;%判断控件的String 是否为 开始
            if strcmp(st, 'Record')
                app.RecordButton.Text = 'Pause';
                app.MrScope_record = true;
                width = 752;
                height = 480;
                frameSize = [width,height];
                app.MrScope_recordstop = false;
                date = datestr(datetime);
                time = strcat(date(13:14),'_',date(16:17),'_',date(19:20));
                app.path=strcat(app.path,'/',time);
                mkdir(app.path); 
                filename = strcat(app.path, '/',num2str(0),'.avi');
                title=["Frame Number","Time Stamp(ms)","Buffer Index","Note","Left location(absolute)","Right location(absolute)"];
                cell2csv(strcat(app.path,'/timeStamps.csv'),title);
                app.video.open(filename,frameSize,'FourCC','MJPG','FPS',app.framerate);
                %app.video.open(filename,frameSize,'FourCC','MJPG');
                Message(app,' Video recording started ',filename,' created');
                Message(app,'While recording LED = ',num2str(app.LED));
                Message(app,'While recording GAIN = ',num2str(app.GAIN));
                Message(app,'While recording Frame rate = ',num2str(app.framerate));
                %set(app.RecordButton,'Enable','off');
                set(app.StopButton,'Enable','on');
            %暂停/继续录制  
            elseif strcmp(st,'Pause') 
                 app.RecordButton.Text = 'Continue';
                 Message(app,'Record paused');
                 app.MrScope_recordpause = true;
            elseif strcmp(st,'Continue')
                app.RecordButton.Text = 'Pause';
                Message(app,'Record contiued');
                app.MrScope_recordpause = false;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.MrScope_record = false;
            %app.MrScope_recordstop = true;
            app.MrScope_recordstop = true;
            app.MrScope_recordpause = false;
            app.path = app.path(1:end-9);
            Message(app,' Video recording stopped');
            set(app.RecordButton,'Enable','on');
            app.RecordButton.Text = 'Record';
            app.MrScope_recordpause = false;
            set(app.StopButton,'Enable','off');
            app.count = -1;
            %set(app.ChoosereferenceimageButton,'Enable','off');
        end

        % Button pushed function: ClearIndexButton
        function ClearIndexButtonPushed(app, event)
            %app.ClearIndexButton.Enable = 'off';
            %app.DialogApp = MrScopev2_child(app);
            %             cam = cv.VideoCapture(0);
            %             tic
            %             cam.retrieve();
            %             toc
            index_clear = 1;
            save("./settings/index_clear.mat","index_clear");

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
            
        end

        % Callback function
        function DynamicrangeButtonPushed(app, event)
            if(app.MrScope_Connected)  
                app.ClearIndexButton.Enable = 'off';
                app.DynamicRangePanel.Visible = 'on';
                app.DynamicRangePanel.Enable = 'on';

            end
        end

        % Button pushed function: OKButton_3
        function OKButton_2Pushed(app, event)
            app.ClearIndexButton.Enable = 'on';
            app.DynamicRangePanel.Visible = 'off';
            app.DynamicRangePanel.Enable = 'off';
            
        end

        % Drop down opening function: ColormapDropDown_4
        function ColormapDropDownValueChanged(app, event)
            app.colormap_value = app.ColormapDropDown_4.Value;
            
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            app.shot=true;
            app.shotNum=app.shotNum+1;
        end

        % Callback function
        function PauseButtonPushed(app, event)
            app.MrScope_recordpause = true;
            set(app.RecordButton,'Enable','on');
            set(app.ChoosereferenceimageButton,'Enable','off');
            Message(app,' Video recording paused');
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.MrScope_Connected = false;
            value = round(app.Spinner.Value);
            app.LED = value;
            app.ExcitationLEDSlider.Value = value;
            %app.MrScope_Connected = false;
            %SendInitCommands();
            testCommand1(0,ledcommand(app.LED));
            Message(app,'Led changed to ',app.LED);
            pause(0.001);
            app.MrScope_Connected = true;
            ConnecttoMrScopeButtonPushed(app);
            
        end

        % Value changed function: ExcitationLEDSlider
        function ExcitationLEDSliderValueChanged(app, event)
            app.MrScope_Connected = false;
            value = round(app.ExcitationLEDSlider.Value);
            app.LED = value;
            app.Spinner.Value = value;
            
            %SendInitCommands();
            testCommand1(0,ledcommand(app.LED));
            Message(app,'Led changed to ',app.LED);
            pause(0.001);
            app.MrScope_Connected = true;
            ConnecttoMrScopeButtonPushed(app);
            
        end

        % Value changing function: ExcitationLEDSlider
        function ExcitationLEDSliderValueChanging2(app, event)
            app.MrScope_Connected = false;
            changingValue = event.Value;
            
        end

        % Callback function
        function ResendButtonPushed(app, event)
            app.MrScope_Connected = false;
            SendInitCommands();
            testCommand1(0,ledcommand(app.LED));
            pause(0.001);
            app.MrScope_Connected = true;
            ConnecttoMrScopeButtonPushed(app);
        end

        % Value changing function: Spinner
        function SpinnerValueChanging2(app, event)
            app.MrScope_Connected = false;
            
        end

        % Value changing function: GainSlider
        function GainSliderValueChanging(app, event)
            app.MrScope_Connected = false;
            changingValue = event.Value;
            
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            app.MrScope_Connected = false;
            value=app.GainSlider.Value;
            set_gain = [1,2,4];
            [~, index] = min(abs(set_gain(:)-value));
            app.GAIN = round(set_gain(index)*16);
            app.ColormapDropDown_2.Value=num2str(round(set_gain(index)));
            testCommand1(0,gaincommand(app.GAIN));
            Message(app,'Gain changed to ',app.GAIN);
            pause(0.001);
            app.MrScope_Connected = true;
            ConnecttoMrScopeButtonPushed(app);

            
        end

        % Value changing function: FramerateSlider
        function FramerateSliderValueChanging(app, event)
            app.MrScope_Connected = false;
            changingValue = event.Value;
            
        end

        % Value changed function: FramerateSlider
        function FramerateSliderValueChanged(app, event)
            app.MrScope_Connected = false;
            value = app.FramerateSlider.Value;
            set_rate = [10,15,20,30,60];
            [~, index] = min(abs(set_rate(:)-value));
            app.framerate = round(set_rate(index));
            app.FramerateSlider.Value = app.framerate;
            app.ColormapDropDown_3.Value=num2str(round((app.framerate)));
            testCommand1(frameratecommand1(app.framerate),frameratecommand2(app.framerate));
            Message(app,'Frame rate changed to ',app.framerate);
            pause(0.001);
            app.MrScope_Connected = true;
            
            if app.framerate<=20 && app.motor_Connected
                set(app.LeftEditField,'Enable','on');
                set(app.RightEditField,'Enable','on');
                set(app.LeftSpinner,'Enable','on');
                set(app.RightSpinner,'Enable','on');
                set(app.TogetherSpinner,'Enable','on');
            end
            if app.framerate>20 || ~app.motor_Connected
                set(app.LeftEditField,'Enable','off');
                set(app.RightEditField,'Enable','off');
                set(app.LeftSpinner,'Enable','off');
                set(app.RightSpinner,'Enable','off');
                set(app.TogetherSpinner,'Enable','off');
            end
            ConnecttoMrScopeButtonPushed(app);
        end

        % Value changed function: ColormapDropDown_3
        function ColormapDropDown_3ValueChanged(app, event)
            value = app.ColormapDropDown_3.Value;
            app.MrScope_Connected = false;
            app.framerate = round(str2double(app.ColormapDropDown_3.Value));
            app.FramerateSlider.Value = app.framerate;
            testCommand1(frameratecommand1(app.framerate),frameratecommand2(app.framerate));
            Message(app,'Frame rate changed to ',app.framerate);
            pause(0.001);
            app.MrScope_Connected = true;
            
            if app.framerate<=20 && app.motor_Connected
                set(app.LeftEditField,'Enable','on');
                set(app.RightEditField,'Enable','on');
                set(app.LeftSpinner,'Enable','on');
                set(app.RightSpinner,'Enable','on');
                set(app.TogetherSpinner,'Enable','on');
            end
            if app.framerate>20 || ~app.motor_Connected
                set(app.LeftEditField,'Enable','off');
                set(app.RightEditField,'Enable','off');
                set(app.LeftSpinner,'Enable','off');
                set(app.RightSpinner,'Enable','off');
                set(app.TogetherSpinner,'Enable','off');
            end
            ConnecttoMrScopeButtonPushed(app);
        end

        % Drop down opening function: ColormapDropDown_3
        function ColormapDropDown_3Opening(app, event)
            app.MrScope_Connected = false;
        end

        % Value changed function: ColormapDropDown_2
        function ColormapDropDown_2ValueChanged(app, event)
            %value = app.ColormapDropDown_2.Value;
            app.MrScope_Connected = false;
            value = round(str2double(app.ColormapDropDown_2.Value));
            app.GainSlider.Value = value;
            app.GAIN = round(value*16);
            gaincommand(app.GAIN)
            testCommand1(0,gaincommand(app.GAIN));
            Message(app,'Gain changed to ',app.GAIN);
            pause(0.001);
            app.MrScope_Connected = true;
            ConnecttoMrScopeButtonPushed(app);
            
        end

        % Drop down opening function: ColormapDropDown_2
        function ColormapDropDown_2Opening(app, event)
            app.MrScope_Connected = false;
        end

        % Value changed function: ColormapDropDown_4
        function ColormapDropDown_4ValueChanged(app, event)
            app.colormap_value = app.ColormapDropDown_4.Value;
            color_map=app.colormap_value;
            save("./settings/color_map.mat","color_map");
            Message(app,"The colorbar is set to ", app.colormap_value);
        end

        % Value changing function: Spinner_7
        function Spinner_7ValueChanging(app, event)
            value = event.Value;
            %%% 动态范围低值
            if value < app.upper_value
                app.lower_value = value;
                low=app.lower_value;
                save("./settings/low.mat","low");
            end
            
        end

        % Value changing function: Spinner_6
        function Spinner_6ValueChanging(app, event)
            value = event.Value;
            %%% 动态范围高值
            %changingValue = event.Value;
            if value > app.lower_value
                app.upper_value = value;
                up=app.upper_value;
                save("./settings/up.mat","up");
            end
        end

        % Value changed function: RecordinglengthsEditField
        function RecordinglengthsEditFieldValueChanged(app, event)
            value = app.RecordinglengthsEditField.Value;
            app.set_record_time = value;
            if (app.set_record_time ~= 0)
                app.set_time_record = true;
            end
        end

        % Value changed function: Spinner_7
        function Spinner_7ValueChanged(app, event)
            value = app.Spinner_7.Value;
            low=app.lower_value;
            save("./settings/low.mat","low");
            Message(app,"The dynamic range is from ", app.lower_value," to ",app.upper_value);
        end

        % Value changed function: Spinner_6
        function Spinner_6ValueChanged(app, event)
            value = app.Spinner_6.Value;
            Message(app,"The dynamic range is from ", app.lower_value," to ",app.upper_value);
        end

        % Callback function
        function ChoosereferenceimageButtonPushed2(app, event)
            [file,path0] = uigetfile('*.tif');
            imwrite(imread(strcat(path0,file)),'.\settings\ref.tif')

        end

        % Value changed function: LeftSpinner
        function LeftSpinnerValueChanged(app, event)
            value = app.LeftSpinner.Value;
            app.h1_loc = value;
            mat_moveabspos(app.h1,app.h1_loc);
            app.LeftEditField_2.Value = num2str(app.h1_loc);
            Message(app,"The location of left moter is set to ", app.h1_loc);
        end

        % Value changed function: RightSpinner
        function RightSpinnerValueChanged(app, event)
            value = app.RightSpinner.Value;
            app.h2_loc = value;
            mat_moveabspos(app.h2,app.h2_loc);
            app.RightEditField_2.Value = num2str(app.h2_loc);
            Message(app,"The location of left moter is set to ", app.h2_loc);
        end

        % Value changed function: TogetherSpinner
        function TogetherSpinnerValueChanged(app, event)
            value = app.TogetherSpinner.Value;
            mat_samelength(app.h1,app.h2,value);
            app.h1_loc = app.h1_loc+value;
            app.h2_loc = app.h2_loc+value;
            app.LeftSpinner.Value = app.h1_loc;
            app.RightSpinner.Value = app.h2_loc;
            app.TogetherSpinner.Value = 0;
            app.LeftEditField_2.Value = num2str(app.h1_loc);
            app.RightEditField_2.Value = num2str(app.h2_loc);
            Message(app,"The location of left moter is set to ", app.h1_loc);
            Message(app,"The location of right moter is set to ", app.h2_loc);
        end

        % Button pushed function: StartupButton
        function StartupButtonPushed(app, event)
                        %% 位移台
            %             global h1; % make h a global variable so it can be used outside the main
            %             global h2;          % function. Useful when you do event handling and sequential   move
                %% Create Matlab Figure Container
                fpos    = get(0,'DefaultFigurePosition'); % figure default position
                fpos(1) = 450;
                fpos(2) = 100;
                fpos(3) = 500; % figure window size;Width
                fpos(4) = 700; % Height

                f = figure('Position', fpos,...
                    'Menu','None',...
                    'Name','APT GUI');
                %% Create ActiveX Controller
                app.h1 = actxcontrol('MGMOTOR.MGMotorCtrl.1',[20 340 487.5 325 ], f);
                app.h2 = actxcontrol('MGMOTOR.MGMotorCtrl.1',[20 20 487.5 325 ], f);

                %% Initialize
                % Start Control
                app.h1.StartCtrl;
                app.h2.StartCtrl;

                % Set the Serial Number
                SN = 26004285; % put in the serial number of the hardware
                set(app.h1,'HWSerialNum', SN);
                SN = 26004469; % put in the serial number of the hardware
                set(app.h2,'HWSerialNum', SN);

                % Indentify the device
                app.h1.Identify;
                app.h2.Identify;

                pause(0.01); % waiting for the GUI to load up;
                %% Controlling the Hardware
                app.h1.MoveHome(0,0); % Home the stage. First 0 is the channel ID (channel 1)
                app.h2.MoveHome(0,0);% second 0 is to move immediately
                app.h1_loc = 0;
                app.h2_loc = 0;
%                 app.h1_start = 0;
%                 app.h2_start = 0;
                app.h1_stop = 0;
                app.h2_stop = 0;
                app.h1_step = 0.01;
                app.h2_step = 0.01;
                
                Message(app,"The electric moving stages initialized successfully.");
                app.motor_Connected = true;
                set(app.LeftSpinner,'Enable','on');
                set(app.RightSpinner,'Enable','on');
                set(app.TogetherSpinner,'Enable','on');
                set(app.StartupButton,'Enable','off');
                set(app.LeftEditField,'Enable','on');
                set(app.RightEditField,'Enable','on');
                set(app.LeftEditField_2,'Enable','on');
                set(app.RightEditField_2,'Enable','on');
                set(app.LeftEditField_3,'Enable','on');
                set(app.RightEditField_3,'Enable','on');
                set(app.LeftEditField_4,'Enable','on');
                set(app.RightEditField_4,'Enable','on');
                set(app.TimeGapEditField,'Enable','on');
        end

        % Value changed function: LeftEditField_2
        function LeftEditField_2ValueChanged(app, event)
            value = app.LeftEditField_2.Value;
            %app.h1_start = value;
%             app.h1_loc = value;
%             if app.
%             mat_moveabspos(app.h1,app.h1_loc);
        end

        % Value changed function: LeftEditField_3
        function LeftEditField_3ValueChanged(app, event)
            value = app.LeftEditField_3.Value;
            app.h1_stop = str2double(value);
            Message(app,strcat("app.h1_stop=",num2str(app.h1_stop)));
        end

        % Value changed function: LeftEditField_4
        function LeftEditField_4ValueChanged(app, event)
            value = app.LeftEditField_4.Value;
            app.h1_step = str2double(value);
            set(app.LeftSpinner,'step',double(app.h1_step));
            Message(app,strcat("app.h1_step=",num2str(app.h1_step)));
        end

        % Value changed function: RightEditField_2
        function RightEditField_2ValueChanged(app, event)
            value = app.RightEditField_2.Value;
%             app.h2_start = value;
%             app.h2_loc = value;
%             mat_moveabspos(app.h2,app.h2_loc);
        end

        % Value changed function: RightEditField_3
        function RightEditField_3ValueChanged(app, event)
            value = app.RightEditField_3.Value;
            app.h2_stop = str2double(value);
             Message(app,strcat("app.h2_stop=",num2str(app.h2_stop)));
        end

        % Value changed function: RightEditField_4
        function RightEditField_4ValueChanged(app, event)
            value = app.RightEditField_4.Value;
            app.h2_step = str2double(value);
            set(app.RightSpinner,'step',double(app.h2_step));
            Message(app,strcat("app.h2_step=",num2str(app.h2_step)));
        end

        % Value changed function: TimeGapEditField
        function TimeGapEditFieldValueChanged(app, event)
            value = app.TimeGapEditField.Value;
            app.time_gap = round(str2double(value)*app.framerate);
            Message(app,strcat("Frame for each plane=",num2str(app.time_gap)));
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            
            Message(app,"Automatic moving starts.");
            app.step_moving = true;
            app.frame_count = 0;
%             app.h1_loc = app.h1_loc + app.h1_step;
%             app.h2_loc = app.h2_loc + app.h2_step;
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            Message(app,"Automatic moving stops.");
            app.step_moving = false;
        end

        % Value changed function: LockleftmoterButton
        function LockleftmoterButtonValueChanged(app, event)
            value = app.LockleftmoterButton.Value;
            if value == 1
                app.h1_stop = app.h1_loc;
                app.LeftEditField_3.Value = num2str(app.h1_stop);
                set(app.LeftSpinner,'Enable','off');
                set(app.LeftEditField,'Enable','off');
                set(app.LeftEditField_2,'Enable','off');
                set(app.LeftEditField_3,'Enable','off');
                set(app.LeftEditField_4,'Enable','off');
            else
                set(app.LeftSpinner,'Enable','on');
                set(app.LeftEditField,'Enable','on');
                set(app.LeftEditField_2,'Enable','on');
                set(app.LeftEditField_3,'Enable','on');
                set(app.LeftEditField_4,'Enable','on');
            end
        end

        % Callback function
        function EntropyadjustmentEditFieldValueChanged(app, event)
            value = app.EntropyadjustmentEditField.Value;
            threshold_en = value;
            save("./settings/threshold_en.mat","threshold_en");
            Message(app,"The hyperparameter of entropy is set to ", value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RecordSettingsPanel = uipanel(app.UIFigure);
            app.RecordSettingsPanel.Title = 'Record Settings';
            app.RecordButton = uibutton(app.RecordSettingsPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.RecordSettingsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SnapshotButton = uibutton(app.RecordSettingsPanel, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Text = 'Snapshot';
            app.RecordinglengthsEditFieldLabel = uilabel(app.RecordSettingsPanel);
            app.RecordinglengthsEditFieldLabel.Text = 'Recording length(s)';
            app.RecordinglengthsEditField = uieditfield(app.RecordSettingsPanel, 'numeric');
            app.RecordinglengthsEditField.ValueChangedFcn = createCallbackFcn(app, @RecordinglengthsEditFieldValueChanged, true);
            app.ConnecttoMrScopeButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoMrScopeButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoMrScopeButtonPushed, true);
            app.ConnecttoMrScopeButton.Text = 'Connect to MrScope';
            app.DisconnecttoMrScopeButton = uibutton(app.UIFigure, 'push');
            app.DisconnecttoMrScopeButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnecttoMrScopeButtonPushed, true);
            app.DisconnecttoMrScopeButton.Text = 'Disonnect to MrScope';
            app.MrScopeSettingsPanel = uipanel(app.UIFigure);
            app.MrScopeSettingsPanel.Title = 'MrScope Settings';
            app.ExcitationLEDLabel = uilabel(app.MrScopeSettingsPanel);
            app.ExcitationLEDLabel.Text = {'Excitation '; 'LED'};
            app.ExcitationLEDSlider = uislider(app.MrScopeSettingsPanel);
            app.ExcitationLEDSlider.Limits = [0 255];
            app.ExcitationLEDSlider.MajorTicks = [0 30 60 90 120 180 255];
            app.ExcitationLEDSlider.ValueChangedFcn = createCallbackFcn(app, @ExcitationLEDSliderValueChanged, true);
            app.ExcitationLEDSlider.ValueChangingFcn = createCallbackFcn(app, @ExcitationLEDSliderValueChanging2, true);
            app.Spinner = uispinner(app.MrScopeSettingsPanel);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging2, true);
            app.Spinner.Limits = [0 255];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.GainSliderLabel = uilabel(app.MrScopeSettingsPanel);
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.MrScopeSettingsPanel);
            app.GainSlider.Limits = [1 4];
            app.GainSlider.MajorTicks = [1 2 4];
            app.GainSlider.MajorTickLabels = {'1', '2', '4', ''};
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainSlider.ValueChangingFcn = createCallbackFcn(app, @GainSliderValueChanging, true);
            app.GainSlider.MinorTicks = [1 2 4];
            app.GainSlider.Value = 1;
            app.FramerateLabel = uilabel(app.MrScopeSettingsPanel);
            app.FramerateLabel.Text = {'Frame'; 'rate'};
            app.FramerateSlider = uislider(app.MrScopeSettingsPanel);
            app.FramerateSlider.Limits = [10 60];
            app.FramerateSlider.MajorTicks = [10 15 20 30 60];
            app.FramerateSlider.ValueChangedFcn = createCallbackFcn(app, @FramerateSliderValueChanged, true);
            app.FramerateSlider.ValueChangingFcn = createCallbackFcn(app, @FramerateSliderValueChanging, true);
            app.FramerateSlider.MinorTicks = [10 15 20 30 60];
            app.FramerateSlider.Value = 10;
            app.ColormapDropDown_2 = uidropdown(app.MrScopeSettingsPanel);
            app.ColormapDropDown_2.Items = {'1', '2', '4'};
            app.ColormapDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @ColormapDropDown_2Opening, true);
            app.ColormapDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_2ValueChanged, true);
            app.ColormapDropDown_2.Value = '1';
            app.ColormapDropDown_3 = uidropdown(app.MrScopeSettingsPanel);
            app.ColormapDropDown_3.Items = {'10', '15', '20', '30', '60'};
            app.ColormapDropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @ColormapDropDown_3Opening, true);
            app.ColormapDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_3ValueChanged, true);
            app.ColormapDropDown_3.Value = '10';
            app.StudyRecordPanel = uipanel(app.UIFigure);
            app.StudyRecordPanel.Title = 'Study Record';
            app.AnimalnameEditFieldLabel = uilabel(app.StudyRecordPanel);
            app.AnimalnameEditFieldLabel.Text = 'Animal name';
            app.AnimalnameEditField = uieditfield(app.StudyRecordPanel, 'text');
            app.ExperimentnameEditFieldLabel = uilabel(app.StudyRecordPanel);
            app.ExperimentnameEditFieldLabel.Text = 'Experiment name';
            app.ExperimentnameEditField = uieditfield(app.StudyRecordPanel, 'text');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ClearIndexButton = uibutton(app.UIFigure, 'push');
            app.ClearIndexButton.ButtonPushedFcn = createCallbackFcn(app, @ClearIndexButtonPushed, true);
            app.ClearIndexButton.Text = 'Clear Index';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.RealtimeimagePanel_2 = uipanel(app.UIFigure);
            app.RealtimeimagePanel_2.Title = 'Real time image';
            app.ColormapDropDown_4Label = uilabel(app.RealtimeimagePanel_2);
            app.ColormapDropDown_4Label.Text = 'Colormap';
            app.ColormapDropDown_4 = uidropdown(app.RealtimeimagePanel_2);
            app.ColormapDropDown_4.Items = {'gray', 'jet', 'bone', 'spring', 'summer', 'autumn', 'winter', ' '};
            app.ColormapDropDown_4.DropDownOpeningFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_4ValueChanged, true);
            app.ColormapDropDown_4.Value = 'gray';
            app.DynamicRangePanel_2 = uipanel(app.RealtimeimagePanel_2);
            app.DynamicRangePanel_2.Title = 'Dynamic Range';
            app.OKButton_3 = uibutton(app.DynamicRangePanel_2, 'push');
            app.OKButton_3.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_3.Text = 'OK';
            app.Spinner_6 = uispinner(app.DynamicRangePanel_2);
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @Spinner_6ValueChanging, true);
            app.Spinner_6.Limits = [0 255];
            app.Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Spinner_6ValueChanged, true);
            app.Spinner_6.Value = 255;
            app.Spinner_7 = uispinner(app.DynamicRangePanel_2);
            app.Spinner_7.ValueChangingFcn = createCallbackFcn(app, @Spinner_7ValueChanging, true);
            app.Spinner_7.Limits = [0 255];
            app.Spinner_7.ValueChangedFcn = createCallbackFcn(app, @Spinner_7ValueChanged, true);
            app.FPSEditFieldLabel = uilabel(app.RealtimeimagePanel_2);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.RealtimeimagePanel_2, 'numeric');
            app.MotorControlPanel = uipanel(app.UIFigure);
            app.MotorControlPanel.Title = 'Motor Control';
            app.GridLayout = uigridlayout(app.MotorControlPanel);
            app.RecordinglengthsEditFieldLabel_2 = uilabel(app.GridLayout);
            app.RecordinglengthsEditFieldLabel_2.Text = {'Real'; 'time'};
            app.RightEditField = uieditfield(app.GridLayout, 'text');
            app.RightEditField.Enable = 'off';
            app.LeftEditField = uieditfield(app.GridLayout, 'text');
            app.LeftEditField.Enable = 'off';
            app.StartupButton = uibutton(app.GridLayout, 'push');
            app.StartupButton.ButtonPushedFcn = createCallbackFcn(app, @StartupButtonPushed, true);
            app.StartupButton.Text = 'Start up';
            app.LeftSpinner = uispinner(app.GridLayout);
            app.LeftSpinner.Step = 0.01;
            app.LeftSpinner.Limits = [0 25];
            app.LeftSpinner.ValueChangedFcn = createCallbackFcn(app, @LeftSpinnerValueChanged, true);
            app.LeftSpinner.Enable = 'off';
            app.LeftSpinnerLabel = uilabel(app.GridLayout);
            app.LeftSpinnerLabel.Enable = 'off';
            app.LeftSpinnerLabel.Text = 'Left';
            app.RightSpinner = uispinner(app.GridLayout);
            app.RightSpinner.Step = 0.01;
            app.RightSpinner.Limits = [0 25];
            app.RightSpinner.ValueChangedFcn = createCallbackFcn(app, @RightSpinnerValueChanged, true);
            app.RightSpinner.Enable = 'off';
            app.RightSpinnerLabel = uilabel(app.GridLayout);
            app.RightSpinnerLabel.Enable = 'off';
            app.RightSpinnerLabel.Text = 'Right';
            app.TogetherSpinner = uispinner(app.GridLayout);
            app.TogetherSpinner.Step = 0.001;
            app.TogetherSpinner.Limits = [-25 25];
            app.TogetherSpinner.ValueChangedFcn = createCallbackFcn(app, @TogetherSpinnerValueChanged, true);
            app.TogetherSpinner.Enable = 'off';
            app.TogetherSpinnerLabel = uilabel(app.GridLayout);
            app.TogetherSpinnerLabel.Enable = 'off';
            app.TogetherSpinnerLabel.Text = 'Together';
            app.RecordinglengthsEditFieldLabel_3 = uilabel(app.GridLayout);
            app.RecordinglengthsEditFieldLabel_3.Text = 'Start';
            app.RecordinglengthsEditFieldLabel_4 = uilabel(app.GridLayout);
            app.RecordinglengthsEditFieldLabel_4.Text = 'Stop';
            app.LeftEditField_2 = uieditfield(app.GridLayout, 'text');
            app.LeftEditField_2.ValueChangedFcn = createCallbackFcn(app, @LeftEditField_2ValueChanged, true);
            app.LeftEditField_2.Enable = 'off';
            app.LeftEditField_3 = uieditfield(app.GridLayout, 'text');
            app.LeftEditField_3.ValueChangedFcn = createCallbackFcn(app, @LeftEditField_3ValueChanged, true);
            app.LeftEditField_3.Enable = 'off';
            app.RightEditField_2 = uieditfield(app.GridLayout, 'text');
            app.RightEditField_2.ValueChangedFcn = createCallbackFcn(app, @RightEditField_2ValueChanged, true);
            app.RightEditField_2.Enable = 'off';
            app.RightEditField_3 = uieditfield(app.GridLayout, 'text');
            app.RightEditField_3.ValueChangedFcn = createCallbackFcn(app, @RightEditField_3ValueChanged, true);
            app.RightEditField_3.Enable = 'off';
            app.RecordinglengthsEditFieldLabel_5 = uilabel(app.GridLayout);
            app.RecordinglengthsEditFieldLabel_5.Text = 'Step';
            app.LeftEditField_4 = uieditfield(app.GridLayout, 'text');
            app.LeftEditField_4.ValueChangedFcn = createCallbackFcn(app, @LeftEditField_4ValueChanged, true);
            app.LeftEditField_4.Enable = 'off';
            app.RightEditField_4 = uieditfield(app.GridLayout, 'text');
            app.RightEditField_4.ValueChangedFcn = createCallbackFcn(app, @RightEditField_4ValueChanged, true);
            app.RightEditField_4.Enable = 'off';
            app.RecordinglengthsEditFieldLabel_6 = uilabel(app.GridLayout);
            app.RecordinglengthsEditFieldLabel_6.Text = {'Time'; 'gap(s)'};
            app.TimeGapEditField = uieditfield(app.GridLayout, 'text');
            app.TimeGapEditField.ValueChangedFcn = createCallbackFcn(app, @TimeGapEditFieldValueChanged, true);
            app.TimeGapEditField.Enable = 'off';
            app.GoButton = uibutton(app.GridLayout, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.StopButton_2 = uibutton(app.GridLayout, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.LockleftmoterButton = uibutton(app.GridLayout, 'state');
            app.LockleftmoterButton.ValueChangedFcn = createCallbackFcn(app, @LockleftmoterButtonValueChanged, true);
            app.LockleftmoterButton.Text = 'Lock left moter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MrScopev2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end